classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        G0TEditField           matlab.ui.control.NumericEditField
        G0TEditFieldLabel      matlab.ui.control.Label
        r0EditField            matlab.ui.control.NumericEditField
        r0EditFieldLabel       matlab.ui.control.Label
        G0ZEditField           matlab.ui.control.NumericEditField
        G0ZEditFieldLabel      matlab.ui.control.Label
        G0YEditField           matlab.ui.control.NumericEditField
        G0YEditFieldLabel      matlab.ui.control.Label
        AREditField            matlab.ui.control.NumericEditField
        AREditFieldLabel       matlab.ui.control.Label
        AzEditField            matlab.ui.control.NumericEditField
        AzEditFieldLabel       matlab.ui.control.Label
        AyEditField            matlab.ui.control.NumericEditField
        AyEditFieldLabel       matlab.ui.control.Label
        AxEditField            matlab.ui.control.NumericEditField
        AxEditFieldLabel       matlab.ui.control.Label
        nZEditField            matlab.ui.control.NumericEditField
        nZEditFieldLabel       matlab.ui.control.Label
        nYEditField            matlab.ui.control.NumericEditField
        nYEditFieldLabel       matlab.ui.control.Label
        BXEditField            matlab.ui.control.NumericEditField
        BXEditFieldLabel       matlab.ui.control.Label
        BZEditField            matlab.ui.control.NumericEditField
        BZEditFieldLabel       matlab.ui.control.Label
        fVREditField           matlab.ui.control.NumericEditField
        fVREditFieldLabel      matlab.ui.control.Label
        fVzEditField           matlab.ui.control.NumericEditField
        fVzEditFieldLabel      matlab.ui.control.Label
        fVyEditField           matlab.ui.control.NumericEditField
        fVyEditFieldLabel      matlab.ui.control.Label
        fVxEditField           matlab.ui.control.NumericEditField
        fVxEditFieldLabel      matlab.ui.control.Label
        KtREditField           matlab.ui.control.NumericEditField
        KtREditFieldLabel      matlab.ui.control.Label
        KtzEditField           matlab.ui.control.NumericEditField
        KtzEditFieldLabel      matlab.ui.control.Label
        KtyEditField           matlab.ui.control.NumericEditField
        KtyEditFieldLabel      matlab.ui.control.Label
        KTLTEditField          matlab.ui.control.NumericEditField
        KTLTEditFieldLabel     matlab.ui.control.Label
        FitLabel_2             matlab.ui.control.Label
        KTLREditField          matlab.ui.control.NumericEditField
        KTLREditFieldLabel     matlab.ui.control.Label
        KTLZEditField          matlab.ui.control.NumericEditField
        KTLZEditFieldLabel     matlab.ui.control.Label
        KTLYEditField          matlab.ui.control.NumericEditField
        KTLYEditFieldLabel     matlab.ui.control.Label
        KTLXEditField          matlab.ui.control.NumericEditField
        KTLXEditFieldLabel     matlab.ui.control.Label
        KDZPEditField          matlab.ui.control.NumericEditField
        KDZPEditFieldLabel     matlab.ui.control.Label
        KDYPEditField          matlab.ui.control.NumericEditField
        KDYPEditFieldLabel     matlab.ui.control.Label
        KDZmEditField          matlab.ui.control.NumericEditField
        KDZmEditFieldLabel     matlab.ui.control.Label
        KDYmEditField          matlab.ui.control.NumericEditField
        KDYmEditFieldLabel     matlab.ui.control.Label
        G0REditField           matlab.ui.control.NumericEditField
        G0REditFieldLabel      matlab.ui.control.Label
        G0XEditField           matlab.ui.control.NumericEditField
        G0XEditFieldLabel      matlab.ui.control.Label
        nXEditField            matlab.ui.control.NumericEditField
        nXEditFieldLabel       matlab.ui.control.Label
        nTEditField            matlab.ui.control.NumericEditField
        nTEditFieldLabel       matlab.ui.control.Label
        KTEditField            matlab.ui.control.NumericEditField
        KTEditFieldLabel       matlab.ui.control.Label
        KtxEditField           matlab.ui.control.NumericEditField
        KtxEditFieldLabel      matlab.ui.control.Label
        KDTPEditField          matlab.ui.control.NumericEditField
        KDTPEditFieldLabel     matlab.ui.control.Label
        KDTmEditField          matlab.ui.control.NumericEditField
        KDTmEditFieldLabel     matlab.ui.control.Label
        KDRPEditField          matlab.ui.control.NumericEditField
        KDRPEditFieldLabel     matlab.ui.control.Label
        KDRmEditField          matlab.ui.control.NumericEditField
        KDRmEditFieldLabel     matlab.ui.control.Label
        KDXPEditField          matlab.ui.control.NumericEditField
        KDXPEditFieldLabel     matlab.ui.control.Label
        KDXmEditField          matlab.ui.control.NumericEditField
        KDXmEditFieldLabel     matlab.ui.control.Label
        ParameterLabel         matlab.ui.control.Label
        FitLabel               matlab.ui.control.Label
        ParameterLabel_2       matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        GridLayout4            matlab.ui.container.GridLayout
        GridLayout6            matlab.ui.container.GridLayout
        ToolFitButton          matlab.ui.control.Button
        SolveButton            matlab.ui.control.Button
        FrequencyGauge         matlab.ui.control.Gauge
        Label_7                matlab.ui.control.Label
        GridLayout5            matlab.ui.container.GridLayout
        InitialValueGoalLabel  matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        TimeEditField          matlab.ui.control.NumericEditField
        TimeEditFieldLabel     matlab.ui.control.Label
        TpEditField            matlab.ui.control.NumericEditField
        TpEditFieldLabel       matlab.ui.control.Label
        TmEditField            matlab.ui.control.NumericEditField
        TmEditFieldLabel       matlab.ui.control.Label
        RpEditField            matlab.ui.control.NumericEditField
        RpEditFieldLabel       matlab.ui.control.Label
        ZpEditField            matlab.ui.control.NumericEditField
        ZpEditFieldLabel       matlab.ui.control.Label
        YpEditField            matlab.ui.control.NumericEditField
        YpEditFieldLabel       matlab.ui.control.Label
        XpEditField            matlab.ui.control.NumericEditField
        XpEditFieldLabel       matlab.ui.control.Label
        RmEditField            matlab.ui.control.NumericEditField
        RmEditFieldLabel       matlab.ui.control.Label
        ZmEditField            matlab.ui.control.NumericEditField
        ZmEditFieldLabel       matlab.ui.control.Label
        YmEditField            matlab.ui.control.NumericEditField
        YmEditFieldLabel       matlab.ui.control.Label
        XmEditField            matlab.ui.control.NumericEditField
        XmEditFieldLabel       matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%     X = [0;0;0;0;0;0;0;0;33.5918;16.5416];
%             para0 = [
%      0.55   % KDXm
%     ;0.12   % KDXP
%     ;0.31   % KDRm
%     ;0.16   % KDRP
%     ;0      % KDLm
%     ;0      % KDLP
%     ;0.38   % KDTm
%     ;0.22   % KDTP
%     ;0      % KLA
%     ;0      % KtL
%     ;0.18   % Ktx
%     ;0.10   % KT
%     ;1.43   % nT
%     ;3.05   % nX
%     ;173.9  % G0X
%     ;0      % G0L
%     ;99.2   % G0T
%     ;102    % G0R
%     ;0.22   % KDYm
%     ;0.19   % KDZm
%     ;0.09   % KDYP
%     ;0.09   % KDZP
%     ;0.27   % KTLX
%     ;0.10   % KTLY
%     ;0.09   % KTLZ
%     ;0.15   % KTLR
%     ;0      % KTLL
%     ;0.11   % KTLT
%     ;0.09   % Kty
%     ;0.13   % Ktz
%     ;0.08   % KtR
%     ;0.16   % fVx
%     ;0.11   % fVy
%     ;0.07   % fVz
%     ;0      % fVL
%     ;0.009  % fVR
%     ;3.0    % BZ
%     ;3.0    % BX
%     ;0      % BY
%     ;3.4    % nY
%     ;3.6    % nZ
%     ;0.07   % Ax
%     ;0.02   % Ay
%     ;0.34   % Az
%     ;0.013  % AR
%     ;0      % AL
%     ;177    % G0Y
%     ;107    % G0Z
%     ;0.13   % r0
%     ];
currentDir = pwd;  % 获取当前 MATLAB 工作目录的路径
filename = fullfile(currentDir, 'data', 'data.mat');  % 使用 fullfile 函数构建完整的文件路径
load(filename);
            
app.KDXmEditField.Value = para0(1 )
app.KDXPEditField.Value = para0(2 )
app.KDRmEditField.Value = para0(3 )
app.KDRPEditField.Value = para0(4 )

app.KDTmEditField.Value = para0(7 )
app.KDTPEditField.Value = para0(8 )
app.KtxEditField.Value =  para0(11)
app.KTEditField.Value =   para0(12)
app.nTEditField.Value =   para0(13)
app.nXEditField.Value =   para0(14)
app.G0XEditField.Value =  para0(15)
app.G0TEditField.Value =  para0(17)
app.G0REditField.Value =  para0(18)
app.KDYmEditField.Value =  para0(19)
app.KDZmEditField.Value =  para0(20)
app.KDYPEditField.Value =  para0(21)
app.KDZPEditField.Value =  para0(22)
app.KTLXEditField.Value =  para0(23)
app.KTLYEditField.Value =  para0(24)
app.KTLZEditField.Value =  para0(25)
app.KTLREditField.Value =  para0(26)
app.KTLTEditField.Value =  para0(28)
app.KtyEditField.Value =  para0(29)
app.KtzEditField.Value =  para0(30)
app.KtREditField.Value =  para0(31)
app.fVxEditField.Value =  para0(32)
app.fVyEditField.Value =  para0(33)
app.fVzEditField.Value =  para0(34)
app.fVREditField.Value =  para0(36)
app.BZEditField.Value =   para0(37)
app.BXEditField.Value =   para0(38)
app.nYEditField.Value =   para0(40)
app.nZEditField.Value =   para0(41)
app.AxEditField.Value =   para0(42)
app.AyEditField.Value =   para0(43)
app.AzEditField.Value =   para0(44)
app.AREditField.Value =   para0(45)
app.G0YEditField.Value =  para0(47)
app.G0ZEditField.Value =  para0(48)
app.r0EditField.Value =   para0(49)

app.XmEditField.Value = X(1);
app.XpEditField.Value = X(2);
app.YmEditField.Value = X(3);
app.YpEditField.Value = X(4);
app.ZmEditField.Value = X(5);
app.ZpEditField.Value = X(6);
app.RmEditField.Value = X(7);
app.RpEditField.Value = X(8);
app.TmEditField.Value = X(9);
app.TpEditField.Value = X(10);

app.TimeEditField.Value = 1000;

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            para0 = [
                app.KDXmEditField.Value
                ;app.KDXPEditField.Value
                ;app.KDRmEditField.Value
                ;app.KDRPEditField.Value
                ;0
                ;0
                ;app.KDTmEditField.Value
                ;app.KDTPEditField.Value
                ;0
                ;0
                ;app.KtxEditField.Value
                ;app.KTEditField.Value
                ;app.nTEditField.Value
                ;app.nXEditField.Value
                ;app.G0XEditField.Value
                ;0
                ;app.G0TEditField.Value
                ;app.G0REditField.Value
                ;           app.KDYmEditField.Value
                ;          app.KDZmEditField.Value
                ;         app.KDYPEditField.Value
                ;        app.KDZPEditField.Value
                ;       app.KTLXEditField.Value
                ;      app.KTLYEditField.Value
                ;     app.KTLZEditField.Value
                ;    app.KTLREditField.Value
                ;0
                ;   app.KTLTEditField.Value
                ; app.KtyEditField.Value
                ;app.KtzEditField.Value
                ;                        app.KtREditField.Value
                ;                       app.fVxEditField.Value
                ;                      app.fVyEditField.Value
                ;                     app.fVzEditField.Value
                ;0
                ;                   app.fVREditField.Value
                ;                  app.BZEditField.Value
                ;                 app.BXEditField.Value
                ;0
                ;               app.nYEditField.Value
                ;              app.nZEditField.Value
                ;             app.AxEditField.Value
                ;            app.AyEditField.Value
                ;           app.AzEditField.Value
                ;          app.AREditField.Value
                ;0
                ;        app.G0YEditField.Value
                ;       app.G0ZEditField.Value
                ;      app.r0EditField.Value
                ];

            X = [
                app.XmEditField.Value
                ;app.XpEditField.Value
                ;app.YmEditField.Value
                ;app.YpEditField.Value
                ;app.ZmEditField.Value
                ;app.ZpEditField.Value
                ;app.RmEditField.Value
                ;app.RpEditField.Value
                ;app.TmEditField.Value
                ;app.TpEditField.Value
                ];
            tspan = [0:0.1:app.TimeEditField.Value];
            [y,meanCycle] = Q2TriModel3fitNoStandForApp(para0,X,tspan);

            t = tspan;
            
            plot(app.UIAxes,t, y(:, 1),"red", t, y(:, 2),"green", t, y(:, 3),"blue", t, y(:, 4),"cyan", t, y(:, 5),"magenta", t, y(:, 6),"black", t, y(:, 7),"yellow", t, y(:, 8),"red"	, 'LineWidth', 1);
            legend(app.UIAxes,'Xm', 'Xp', 'Ym', 'Yp', 'Zm', 'Zp', 'Rm', 'Rp')

            app.FrequencyGauge.Value = meanCycle;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ToolFitButton
        function ToolFitButtonPushed(app, event)
            para0 = [
                app.KDXmEditField.Value
                ;app.KDXPEditField.Value
                ;app.KDRmEditField.Value
                ;app.KDRPEditField.Value
                ;0
                ;0
                ;app.KDTmEditField.Value
                ;app.KDTPEditField.Value
                ;0
                ;0
                ;app.KtxEditField.Value
                ;app.KTEditField.Value
                ;app.nTEditField.Value
                ;app.nXEditField.Value
                ;app.G0XEditField.Value
                ;0
                ;app.G0TEditField.Value
                ;app.G0REditField.Value
                ;           app.KDYmEditField.Value
                ;          app.KDZmEditField.Value
                ;         app.KDYPEditField.Value
                ;        app.KDZPEditField.Value
                ;       app.KTLXEditField.Value
                ;      app.KTLYEditField.Value
                ;     app.KTLZEditField.Value
                ;    app.KTLREditField.Value
                ;0
                ;   app.KTLTEditField.Value
                ; app.KtyEditField.Value
                ;app.KtzEditField.Value
                ;                        app.KtREditField.Value
                ;                       app.fVxEditField.Value
                ;                      app.fVyEditField.Value
                ;                     app.fVzEditField.Value
                ;0
                ;                   app.fVREditField.Value
                ;                  app.BZEditField.Value
                ;                 app.BXEditField.Value
                ;0
                ;               app.nYEditField.Value
                ;              app.nZEditField.Value
                ;             app.AxEditField.Value
                ;            app.AyEditField.Value
                ;           app.AzEditField.Value
                ;          app.AREditField.Value
                ;0
                ;        app.G0YEditField.Value
                ;       app.G0ZEditField.Value
                ;      app.r0EditField.Value
                ];

            X = [
                app.XmEditField.Value
                ;app.XpEditField.Value
                ;app.YmEditField.Value
                ;app.YpEditField.Value
                ;app.ZmEditField.Value
                ;app.ZpEditField.Value
                ;app.RmEditField.Value
                ;app.RpEditField.Value
                ;app.TmEditField.Value
                ;app.TpEditField.Value
                ];
            tspan = [0:0.1:app.TimeEditField.Value];

            save("/Users/shawn/Desktop/IGEM/code/data.mat","tspan","X","para0");

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {975, 975};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {292, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ParameterLabel_2 = uilabel(app.GridLayout2);
            app.ParameterLabel_2.Text = 'Parameter';
            app.FitLabel = uilabel(app.GridLayout2);
            app.FitLabel.Text = 'Fit';
            app.ParameterLabel = uilabel(app.GridLayout2);
            app.ParameterLabel.Text = 'Parameter';
            app.KDXmEditFieldLabel = uilabel(app.GridLayout2);
            app.KDXmEditFieldLabel.Text = 'KDXm';
            app.KDXmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDXPEditFieldLabel = uilabel(app.GridLayout2);
            app.KDXPEditFieldLabel.Text = 'KDXP';
            app.KDXPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDRmEditFieldLabel = uilabel(app.GridLayout2);
            app.KDRmEditFieldLabel.Text = 'KDRm';
            app.KDRmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDRPEditFieldLabel = uilabel(app.GridLayout2);
            app.KDRPEditFieldLabel.Text = 'KDRP';
            app.KDRPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDTmEditFieldLabel = uilabel(app.GridLayout2);
            app.KDTmEditFieldLabel.Text = 'KDTm';
            app.KDTmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDTPEditFieldLabel = uilabel(app.GridLayout2);
            app.KDTPEditFieldLabel.Text = 'KDTP';
            app.KDTPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KtxEditFieldLabel = uilabel(app.GridLayout2);
            app.KtxEditFieldLabel.Text = 'Ktx';
            app.KtxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KTEditFieldLabel = uilabel(app.GridLayout2);
            app.KTEditFieldLabel.Text = 'KT';
            app.KTEditField = uieditfield(app.GridLayout2, 'numeric');
            app.nTEditFieldLabel = uilabel(app.GridLayout2);
            app.nTEditFieldLabel.Text = 'nT';
            app.nTEditField = uieditfield(app.GridLayout2, 'numeric');
            app.nXEditFieldLabel = uilabel(app.GridLayout2);
            app.nXEditFieldLabel.Text = 'nX';
            app.nXEditField = uieditfield(app.GridLayout2, 'numeric');
            app.G0XEditFieldLabel = uilabel(app.GridLayout2);
            app.G0XEditFieldLabel.Text = 'G0X';
            app.G0XEditField = uieditfield(app.GridLayout2, 'numeric');
            app.G0REditFieldLabel = uilabel(app.GridLayout2);
            app.G0REditFieldLabel.Text = 'G0R';
            app.G0REditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDYmEditFieldLabel = uilabel(app.GridLayout2);
            app.KDYmEditFieldLabel.Text = 'KDYm';
            app.KDYmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDZmEditFieldLabel = uilabel(app.GridLayout2);
            app.KDZmEditFieldLabel.Text = 'KDZm';
            app.KDZmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDYPEditFieldLabel = uilabel(app.GridLayout2);
            app.KDYPEditFieldLabel.Text = 'KDYP';
            app.KDYPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KDZPEditFieldLabel = uilabel(app.GridLayout2);
            app.KDZPEditFieldLabel.Text = 'KDZP';
            app.KDZPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KTLXEditFieldLabel = uilabel(app.GridLayout2);
            app.KTLXEditFieldLabel.Text = 'KTLX';
            app.KTLXEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KTLYEditFieldLabel = uilabel(app.GridLayout2);
            app.KTLYEditFieldLabel.Text = 'KTLY';
            app.KTLYEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KTLZEditFieldLabel = uilabel(app.GridLayout2);
            app.KTLZEditFieldLabel.Text = 'KTLZ';
            app.KTLZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KTLREditFieldLabel = uilabel(app.GridLayout2);
            app.KTLREditFieldLabel.Text = 'KTLR';
            app.KTLREditField = uieditfield(app.GridLayout2, 'numeric');
            app.FitLabel_2 = uilabel(app.GridLayout2);
            app.FitLabel_2.Text = 'Fit';
            app.KTLTEditFieldLabel = uilabel(app.GridLayout2);
            app.KTLTEditFieldLabel.Text = 'KTLT';
            app.KTLTEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KtyEditFieldLabel = uilabel(app.GridLayout2);
            app.KtyEditFieldLabel.Text = 'Kty';
            app.KtyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KtzEditFieldLabel = uilabel(app.GridLayout2);
            app.KtzEditFieldLabel.Text = 'Ktz';
            app.KtzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KtREditFieldLabel = uilabel(app.GridLayout2);
            app.KtREditFieldLabel.Text = 'KtR';
            app.KtREditField = uieditfield(app.GridLayout2, 'numeric');
            app.fVxEditFieldLabel = uilabel(app.GridLayout2);
            app.fVxEditFieldLabel.Text = 'fVx';
            app.fVxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fVyEditFieldLabel = uilabel(app.GridLayout2);
            app.fVyEditFieldLabel.Text = 'fVy';
            app.fVyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fVzEditFieldLabel = uilabel(app.GridLayout2);
            app.fVzEditFieldLabel.Text = 'fVz';
            app.fVzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fVREditFieldLabel = uilabel(app.GridLayout2);
            app.fVREditFieldLabel.Text = 'fVR';
            app.fVREditField = uieditfield(app.GridLayout2, 'numeric');
            app.BZEditFieldLabel = uilabel(app.GridLayout2);
            app.BZEditFieldLabel.Text = 'BZ';
            app.BZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BXEditFieldLabel = uilabel(app.GridLayout2);
            app.BXEditFieldLabel.Text = 'BX';
            app.BXEditField = uieditfield(app.GridLayout2, 'numeric');
            app.nYEditFieldLabel = uilabel(app.GridLayout2);
            app.nYEditFieldLabel.Text = 'nY';
            app.nYEditField = uieditfield(app.GridLayout2, 'numeric');
            app.nZEditFieldLabel = uilabel(app.GridLayout2);
            app.nZEditFieldLabel.Text = 'nZ';
            app.nZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AxEditFieldLabel = uilabel(app.GridLayout2);
            app.AxEditFieldLabel.Text = 'Ax';
            app.AxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AyEditFieldLabel = uilabel(app.GridLayout2);
            app.AyEditFieldLabel.Text = 'Ay';
            app.AyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AzEditFieldLabel = uilabel(app.GridLayout2);
            app.AzEditFieldLabel.Text = 'Az';
            app.AzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AREditFieldLabel = uilabel(app.GridLayout2);
            app.AREditFieldLabel.Text = 'AR';
            app.AREditField = uieditfield(app.GridLayout2, 'numeric');
            app.G0YEditFieldLabel = uilabel(app.GridLayout2);
            app.G0YEditFieldLabel.Text = 'G0Y';
            app.G0YEditField = uieditfield(app.GridLayout2, 'numeric');
            app.G0ZEditFieldLabel = uilabel(app.GridLayout2);
            app.G0ZEditFieldLabel.Text = 'G0Z';
            app.G0ZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.r0EditFieldLabel = uilabel(app.GridLayout2);
            app.r0EditFieldLabel.Text = 'r0';
            app.r0EditField = uieditfield(app.GridLayout2, 'numeric');
            app.G0TEditFieldLabel = uilabel(app.GridLayout2);
            app.G0TEditFieldLabel.Text = 'G0T';
            app.G0TEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.XmEditFieldLabel = uilabel(app.GridLayout5);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout5, 'numeric');
            app.YmEditFieldLabel = uilabel(app.GridLayout5);
            app.YmEditFieldLabel.Text = 'Ym';
            app.YmEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ZmEditFieldLabel = uilabel(app.GridLayout5);
            app.ZmEditFieldLabel.Text = 'Zm';
            app.ZmEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RmEditFieldLabel = uilabel(app.GridLayout5);
            app.RmEditFieldLabel.Text = 'Rm';
            app.RmEditField = uieditfield(app.GridLayout5, 'numeric');
            app.XpEditFieldLabel = uilabel(app.GridLayout5);
            app.XpEditFieldLabel.Text = 'Xp';
            app.XpEditField = uieditfield(app.GridLayout5, 'numeric');
            app.YpEditFieldLabel = uilabel(app.GridLayout5);
            app.YpEditFieldLabel.Text = 'Yp';
            app.YpEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ZpEditFieldLabel = uilabel(app.GridLayout5);
            app.ZpEditFieldLabel.Text = 'Zp';
            app.ZpEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RpEditFieldLabel = uilabel(app.GridLayout5);
            app.RpEditFieldLabel.Text = 'Rp';
            app.RpEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TmEditFieldLabel = uilabel(app.GridLayout5);
            app.TmEditFieldLabel.Text = 'Tm';
            app.TmEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TpEditFieldLabel = uilabel(app.GridLayout5);
            app.TpEditFieldLabel.Text = 'Tp';
            app.TpEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.GridLayout5);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.Label_6 = uilabel(app.GridLayout5);
            app.Label_6.Text = '';
            app.InitialValueGoalLabel = uilabel(app.GridLayout5);
            app.InitialValueGoalLabel.Text = 'InitialValue/Goal';
            app.Label_7 = uilabel(app.GridLayout4);
            app.Label_7.Text = 'Frequency';
            app.FrequencyGauge = uigauge(app.GridLayout4, 'circular');
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.SolveButton = uibutton(app.GridLayout6, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ToolFitButton = uibutton(app.GridLayout6, 'push');
            app.ToolFitButton.ButtonPushedFcn = createCallbackFcn(app, @ToolFitButtonPushed, true);
            app.ToolFitButton.Text = 'Tool Fit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end